classdef inst_amp_estimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DurationinsEditField       matlab.ui.control.NumericEditField
        DurationinsEditFieldLabel  matlab.ui.control.Label
        ObtainEnvelopeButton       matlab.ui.control.Button
        ApplyFilterButton          matlab.ui.control.Button
        TrimAudioButton            matlab.ui.control.Button
        OrderoftheMovingAverageFilterEditField  matlab.ui.control.NumericEditField
        OrderoftheMovingAverageFilterEditFieldLabel  matlab.ui.control.Label
        LoadAudioButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       y;
       Fs = 2000;
       t;
       duration;
       a0 = 0;
       b0 = 20000;
       inst_amp;
       tap;
    end
    
    methods (Access = private)
        
        function [y] = R2_DIT(~, x, type)              %function computes FFT when type = -1 and IFFT when type = 1
            p=2^nextpow2(length(x));
            x=[x zeros(1,p-length(x))];
            N=length(x);
            S=log2(N);
            Half=1;
            x=bitrevorder(x);
            for stage=1:S
                for index=0:(2^stage):(N-1)
                    for n=0:(Half-1)
                        pos=n+index+1;
                        pow=(2^(S-stage))*n;
                        w=exp((type*1j)*(2*pi)*pow/N);
                        a=x(pos)+x(pos+Half).*w;
                        b=x(pos)-x(pos+Half).*w;
                        x(pos)=a;
                        x(pos+Half)=b;
                    end
                end
            Half=2*Half;
            end
            if type == 1
                y = x/N;
            else
                y = x;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.duration = app.DurationinsEditField.Value;
            app.tap = app.OrderoftheMovingAverageFilterEditField.Value;
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [app.y, app.Fs] = audioread("beats.wav");
            app.y = (app.y)';
            app.t = (0:length(app.y)-1)/app.Fs;
            plot(app.UIAxes,app.t, app.y);
            title(app.UIAxes,"Heart Sound Signal Sampled at 2kHz");
            xlabel(app.UIAxes, "Time (s)");
            xlim(app.UIAxes, [app.a0/app.Fs app.b0/app.Fs]);
        end

        % Button pushed function: TrimAudioButton
        function TrimAudioButtonPushed(app, event)
            app.a0 = 2*app.Fs;
            if (app.duration < 8)
               app.b0 = (2+app.duration)*app.Fs; 
            else
               app.b0 = (10)*app.Fs; 
            end
            plot(app.UIAxes,app.t(app.a0:app.b0), app.y(app.a0:app.b0));
            title(app.UIAxes,"Heart Sound Signal Sampled at 2kHz");
            xlim(app.UIAxes, [app.a0/app.Fs app.b0/app.Fs]);
        end

        % Value changed function: DurationinsEditField
        function DurationinsEditFieldValueChanged(app, event)
            app.duration = app.DurationinsEditField.Value;
        end

        % Button pushed function: ObtainEnvelopeButton
        function ObtainEnvelopeButtonPushed(app, event)
            y_fft = R2_DIT(app, app.y, -1);
            complex_y_fft = 1j*y_fft;
            n = length(y_fft);
            positive_frequencies = 2:floor(n/2)+mod(n, 2);
            negative_frequencies = ceil(n/2)+1+~mod(n, 2):n;

            y_fft(positive_frequencies) = y_fft(positive_frequencies) + -1j*complex_y_fft(positive_frequencies);
            y_fft(negative_frequencies) = y_fft(negative_frequencies) +  1j*complex_y_fft(negative_frequencies);

            z = R2_DIT(app, y_fft, 1);

            app.inst_amp = abs(z);
            plot(app.UIAxes,app.t(app.a0:app.b0), app.y(app.a0:app.b0));
            hold(app.UIAxes, "on");
            plot(app.UIAxes,app.t(app.a0:app.b0), app.inst_amp(app.a0:app.b0));
            hold(app.UIAxes, "off");
            xlim(app.UIAxes, [app.a0/app.Fs app.b0/app.Fs]);
            title(app.UIAxes, "Instaneous Ampltiude Envelope");
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            L = app.tap;                                    % Designing an MA filter to smoothen the envelope
            b = (1/L)*ones(1, L);
            a = 1;
            n = length(app.inst_amp);
            inst_amp_smooth = zeros(n, 1);

            for i = 1:n
            temp = 0;
                for j = 1:L
                    if (i-j > 0)
                        temp = temp + app.inst_amp(i-j);
                    end
                end
                inst_amp_smooth(i) = temp/L;
                temp = 0;
            end
            
            delay = (L-1)/2;
            plot(app.UIAxes,app.t(app.a0:app.b0), app.y(app.a0:app.b0));
            hold(app.UIAxes, "on");
            plot(app.UIAxes,app.t(app.a0:app.b0)-(delay/app.Fs), inst_amp_smooth(app.a0:app.b0));
            hold(app.UIAxes, "off");
            xlim(app.UIAxes, [app.a0/app.Fs app.b0/app.Fs]);
            title(app.UIAxes, "Smooth Instaneous Ampltiude Envelope");
        end

        % Value changed function: 
        % OrderoftheMovingAverageFilterEditField
        function OrderoftheMovingAverageFilterEditFieldValueChanged(app, event)
            app.tap = app.OrderoftheMovingAverageFilterEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.LoadAudioButton = uibutton(app.UIFigure, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.OrderoftheMovingAverageFilterEditFieldLabel = uilabel(app.UIFigure);
            app.OrderoftheMovingAverageFilterEditFieldLabel.Text = 'Order of the Moving Average Filter';
            app.OrderoftheMovingAverageFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderoftheMovingAverageFilterEditField.ValueChangedFcn = createCallbackFcn(app, @OrderoftheMovingAverageFilterEditFieldValueChanged, true);
            app.OrderoftheMovingAverageFilterEditField.Value = 100;
            app.TrimAudioButton = uibutton(app.UIFigure, 'push');
            app.TrimAudioButton.ButtonPushedFcn = createCallbackFcn(app, @TrimAudioButtonPushed, true);
            app.TrimAudioButton.Text = 'Trim Audio';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ObtainEnvelopeButton = uibutton(app.UIFigure, 'push');
            app.ObtainEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainEnvelopeButtonPushed, true);
            app.ObtainEnvelopeButton.Text = 'Obtain Envelope';
            app.DurationinsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationinsEditFieldLabel.Text = 'Duration (in s)';
            app.DurationinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationinsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationinsEditFieldValueChanged, true);
            app.DurationinsEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inst_amp_estimator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end